classdef FINAL_MODEL0_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SOLARPOWERLabel              matlab.ui.control.Label
        TimeScope5_2                 matlab.ui.scope.TimeScope
        LPSPLabel                    matlab.ui.control.Label
        Gauge2                       matlab.ui.control.NinetyDegreeGauge
        Gauge2Label                  matlab.ui.control.Label
        TimeScope6                   matlab.ui.scope.TimeScope
        Gauge                        matlab.ui.control.NinetyDegreeGauge
        TimeScope5                   matlab.ui.scope.TimeScope
        TimeScope4                   matlab.ui.scope.TimeScope
        TimeScope3                   matlab.ui.scope.TimeScope
        TimeScope2                   matlab.ui.scope.TimeScope
        TimeScope                    matlab.ui.scope.TimeScope
        BATTERYPOWERGauge            matlab.ui.control.LinearGauge
        BATTERYPOWERGaugeLabel       matlab.ui.control.Label
        CSPPOWERGauge                matlab.ui.control.LinearGauge
        CSPPOWERGaugeLabel           matlab.ui.control.Label
        SOLARPOWERGauge              matlab.ui.control.LinearGauge
        MODE3Lamp                    matlab.ui.control.Lamp
        MODE3LampLabel               matlab.ui.control.Label
        MODE2Lamp                    matlab.ui.control.Lamp
        MODE2LampLabel               matlab.ui.control.Label
        MODE1Lamp                    matlab.ui.control.Lamp
        MODE1LampLabel               matlab.ui.control.Label
        BATTERYLamp                  matlab.ui.control.Lamp
        BATTERYLampLabel             matlab.ui.control.Label
        POWER_DEMANDEditField        matlab.ui.control.NumericEditField
        POWER_DEMANDEditFieldLabel   matlab.ui.control.Label
        MASS_FULLEditField           matlab.ui.control.NumericEditField
        MASS_FULLEditFieldLabel      matlab.ui.control.Label
        NUMBER_HELIOSEditField       matlab.ui.control.NumericEditField
        NUMBER_HELIOSEditFieldLabel  matlab.ui.control.Label
        AREA_HELIOSEditField         matlab.ui.control.NumericEditField
        AREA_HELIOSEditFieldLabel    matlab.ui.control.Label
        WINDSlider                   matlab.ui.control.Slider
        WINDSliderLabel              matlab.ui.control.Label
        NUMBEROFCELLSEditField       matlab.ui.control.NumericEditField
        NUMBEROFCELLSEditFieldLabel  matlab.ui.control.Label
        SOLARFIELDEditField          matlab.ui.control.NumericEditField
        SOLARFIELDEditFieldLabel     matlab.ui.control.Label
        SMSlider                     matlab.ui.control.Slider
        SMSliderLabel                matlab.ui.control.Label
        CITYDropDown                 matlab.ui.control.DropDown
        CITYDropDownLabel            matlab.ui.control.Label
        SimulationControls           simulink.ui.control.SimulationControls
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SOLARFIELDEditField
        function SOLARFIELDEditFieldValueChanged(app, event)
            value = app.SOLARFIELDEditField.Value;
            assignin('base','SOLAR_PANELS',value);
        end

        % Value changed function: NUMBEROFCELLSEditField
        function NUMBEROFCELLSEditFieldValueChanged(app, event)
            value = app.NUMBEROFCELLSEditField.Value;
            assignin('base','NUMBER_OF_CELLS',value);
        end

        % Value changed function: POWER_DEMANDEditField
        function POWER_DEMANDEditFieldValueChanged(app, event)
            value = app.POWER_DEMANDEditField.Value;
             assignin('base','POWER_DEMAND',value);
        end

        % Value changed function: AREA_HELIOSEditField
        function AREA_HELIOSEditFieldValueChanged(app, event)
            value = app.AREA_HELIOSEditField.Value;
            assignin('base','AREA_HELIOS',value);
        end

        % Value changed function: NUMBER_HELIOSEditField
        function NUMBER_HELIOSEditFieldValueChanged(app, event)
            value = app.NUMBER_HELIOSEditField.Value;
            assignin('base','NUMBER_HELIOS',value);
        end

        % Value changed function: MASS_FULLEditField
        function MASS_FULLEditFieldValueChanged(app, event)
            value = app.MASS_FULLEditField.Value;
            assignin('base','MASS_FULL_TANK',value);
        end

        % Value changing function: WINDSlider
        function WINDSliderValueChanging(app, event)
            changingValue = event.Value;
            assignin('base','WIND',changingValue);
        end

        % Value changing function: SMSlider
        function SMSliderValueChanging(app, event)
            changingValue = event.Value;
            assignin('base','SOLAR_MULTIPLE',changingValue);
        end

        % Value changed function: CITYDropDown
        function CITYDropDownValueChanged(app, event)
            value = app.CITYDropDown.Value;
            assignin('base','C',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.CITYDropDownLabel = uilabel(app.UIFigure);
            app.CITYDropDownLabel.Text = 'CITY';
            app.CITYDropDown = uidropdown(app.UIFigure);
            app.CITYDropDown.Items = {'DELIAH', 'RIYADH', 'JHODPUR', 'ALICE SPRINGS'};
            app.CITYDropDown.ItemsData = [1 2 3 4];
            app.CITYDropDown.ValueChangedFcn = createCallbackFcn(app, @CITYDropDownValueChanged, true);
            app.CITYDropDown.Value = 1;
            app.SMSliderLabel = uilabel(app.UIFigure);
            app.SMSliderLabel.Text = 'SM';
            app.SMSlider = uislider(app.UIFigure);
            app.SMSlider.Limits = [1 5];
            app.SMSlider.MajorTicks = [1 2 3 4 5];
            app.SMSlider.ValueChangingFcn = createCallbackFcn(app, @SMSliderValueChanging, true);
            app.SMSlider.Value = 1;
            app.SOLARFIELDEditFieldLabel = uilabel(app.UIFigure);
            app.SOLARFIELDEditFieldLabel.Text = 'SOLAR_PANELS';
            app.SOLARFIELDEditField = uieditfield(app.UIFigure, 'numeric');
            app.SOLARFIELDEditField.ValueChangedFcn = createCallbackFcn(app, @SOLARFIELDEditFieldValueChanged, true);
            app.SOLARFIELDEditField.Value = 100;
            app.NUMBEROFCELLSEditFieldLabel = uilabel(app.UIFigure);
            app.NUMBEROFCELLSEditFieldLabel.Text = 'NUMBER_OF_CELLS';
            app.NUMBEROFCELLSEditField = uieditfield(app.UIFigure, 'numeric');
            app.NUMBEROFCELLSEditField.ValueChangedFcn = createCallbackFcn(app, @NUMBEROFCELLSEditFieldValueChanged, true);
            app.NUMBEROFCELLSEditField.Value = 1000;
            app.WINDSliderLabel = uilabel(app.UIFigure);
            app.WINDSliderLabel.Text = 'WIND';
            app.WINDSlider = uislider(app.UIFigure);
            app.WINDSlider.Limits = [1 5];
            app.WINDSlider.MajorTicks = [1 2 3 4 5];
            app.WINDSlider.ValueChangingFcn = createCallbackFcn(app, @WINDSliderValueChanging, true);
            app.WINDSlider.Value = 2.5;
            app.AREA_HELIOSEditFieldLabel = uilabel(app.UIFigure);
            app.AREA_HELIOSEditFieldLabel.Text = 'AREA_HELIOS';
            app.AREA_HELIOSEditField = uieditfield(app.UIFigure, 'numeric');
            app.AREA_HELIOSEditField.ValueChangedFcn = createCallbackFcn(app, @AREA_HELIOSEditFieldValueChanged, true);
            app.AREA_HELIOSEditField.Value = 160;
            app.NUMBER_HELIOSEditFieldLabel = uilabel(app.UIFigure);
            app.NUMBER_HELIOSEditFieldLabel.Text = 'NUMBER_HELIOS';
            app.NUMBER_HELIOSEditField = uieditfield(app.UIFigure, 'numeric');
            app.NUMBER_HELIOSEditField.ValueChangedFcn = createCallbackFcn(app, @NUMBER_HELIOSEditFieldValueChanged, true);
            app.NUMBER_HELIOSEditField.Value = 5000;
            app.MASS_FULLEditFieldLabel = uilabel(app.UIFigure);
            app.MASS_FULLEditFieldLabel.Text = 'MASS_FULL_TANK';
            app.MASS_FULLEditField = uieditfield(app.UIFigure, 'numeric');
            app.MASS_FULLEditField.ValueChangedFcn = createCallbackFcn(app, @MASS_FULLEditFieldValueChanged, true);
            app.MASS_FULLEditField.Value = 25000000;
            app.POWER_DEMANDEditFieldLabel = uilabel(app.UIFigure);
            app.POWER_DEMANDEditFieldLabel.Text = 'POWER_DEMAND';
            app.POWER_DEMANDEditField = uieditfield(app.UIFigure, 'numeric');
            app.POWER_DEMANDEditField.ValueChangedFcn = createCallbackFcn(app, @POWER_DEMANDEditFieldValueChanged, true);
            app.POWER_DEMANDEditField.Value = 200;
            app.BATTERYLampLabel = uilabel(app.UIFigure);
            app.BATTERYLampLabel.Text = 'BATTERY';
            app.BATTERYLamp = uilamp(app.UIFigure);
            app.MODE1LampLabel = uilabel(app.UIFigure);
            app.MODE1LampLabel.Text = 'MODE 1';
            app.MODE1Lamp = uilamp(app.UIFigure);
            app.MODE2LampLabel = uilabel(app.UIFigure);
            app.MODE2LampLabel.Text = 'MODE 2';
            app.MODE2Lamp = uilamp(app.UIFigure);
            app.MODE3LampLabel = uilabel(app.UIFigure);
            app.MODE3LampLabel.Text = 'MODE 3';
            app.MODE3Lamp = uilamp(app.UIFigure);
            app.SOLARPOWERGauge = uigauge(app.UIFigure, 'linear');
            app.SOLARPOWERGauge.Limits = [0 200];
            app.CSPPOWERGaugeLabel = uilabel(app.UIFigure);
            app.CSPPOWERGaugeLabel.Text = 'CSP POWER';
            app.CSPPOWERGauge = uigauge(app.UIFigure, 'linear');
            app.CSPPOWERGauge.Limits = [0 200];
            app.BATTERYPOWERGaugeLabel = uilabel(app.UIFigure);
            app.BATTERYPOWERGaugeLabel.Text = 'BATTERY POWER';
            app.BATTERYPOWERGauge = uigauge(app.UIFigure, 'linear');
            app.BATTERYPOWERGauge.Limits = [0 200];
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'TEMPERATURE DATA:1'};
            app.TimeScope.Title = 'TEMP';
            app.TimeScope.YLabel = 'TEMPARATURE';
            app.TimeScope2 = uitimescope(app.UIFigure);
            app.TimeScope2.LegendNames = {'DNI DATA:1'};
            app.TimeScope2.Title = 'DNI';
            app.TimeScope2.YLabel = 'DNI';
            app.TimeScope3 = uitimescope(app.UIFigure);
            app.TimeScope3.LegendNames = {'Add:1'};
            app.TimeScope3.Title = 'TOTAL POWER';
            app.TimeScope3.YLabel = 'POWER';
            app.TimeScope4 = uitimescope(app.UIFigure);
            app.TimeScope4.LegendNames = {'MATLAB Function3:2'};
            app.TimeScope4.Title = 'SOC BATTERY';
            app.TimeScope4.YLabel = 'SOC';
            app.TimeScope5 = uitimescope(app.UIFigure);
            app.TimeScope5.LegendNames = {'MATLAB Function6:1'};
            app.TimeScope5.Title = 'SOC TES';
            app.TimeScope5.YLabel = 'SOC';
            app.Gauge = uigauge(app.UIFigure, 'ninetydegree');
            app.TimeScope6 = uitimescope(app.UIFigure);
            app.TimeScope6.LegendNames = {'MATLAB Function10:1'};
            app.TimeScope6.Title = 'LCOE';
            app.TimeScope6.YLabel = 'LCOE';
            app.Gauge2Label = uilabel(app.UIFigure);
            app.Gauge2Label.Text = 'SOLAR EFFCIENCY';
            app.Gauge2 = uigauge(app.UIFigure, 'ninetydegree');
            app.Gauge2.Limits = [0 1];
            app.Gauge2.ScaleDirection = 'counterclockwise';
            app.LPSPLabel = uilabel(app.UIFigure);
            app.LPSPLabel.Text = 'LPSP';
            app.TimeScope5_2 = uitimescope(app.UIFigure);
            app.TimeScope5_2.LegendNames = {'MATLAB Function6:1'};
            app.TimeScope5_2.Title = 'SOLAR POWER';
            app.TimeScope5_2.YLabel = 'POWER';
            app.SOLARPOWERLabel = uilabel(app.UIFigure);
            app.SOLARPOWERLabel.Text = 'SOLAR POWER';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.BATTERYLamp.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Add:1'], app.TimeScope3);
            bind(app.AREA_HELIOSEditField, 'Value', app.Simulation.TunableVariables, 'AREA_HELIOS');
            bind(app.MASS_FULLEditField, 'Value', app.Simulation.TunableVariables, 'MASS_FULL_TANK');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CITY SELECTION AND SOLAR PANEL MODEL:1'], app.SOLARPOWERGauge, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CITY SELECTION AND SOLAR PANEL MODEL:1'], app.TimeScope5_2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/CITY SELECTION AND SOLAR PANEL MODEL:2'], app.Gauge2, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/TEMPERATURE DATA:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/MATLAB Function10:1'], app.TimeScope6);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/MATLAB Function10:2'], app.Gauge, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/DNI DATA:1'], app.TimeScope2);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/MATLAB Function3:1'], app.BATTERYPOWERGauge, 'Value');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/MATLAB Function3:2'], app.TimeScope4);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/MATLAB Function6:1'], app.TimeScope5);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/MATLAB Function9:1'], app.CSPPOWERGauge, 'Value');
            bind(app.NUMBER_HELIOSEditField, 'Value', app.Simulation.TunableVariables, 'NUMBER_HELIOS');
            bind(app.NUMBEROFCELLSEditField, 'Value', app.Simulation.TunableVariables, 'NUMBER_OF_CELLS');
            bind(app.POWER_DEMANDEditField, 'Value', app.Simulation.TunableVariables, 'POWER_DEMAND');
            bind(app.SMSlider, 'Value', app.Simulation.TunableVariables, 'SOLAR_MULTIPLE');
            bind(app.SOLARFIELDEditField, 'Value', app.Simulation.TunableVariables, 'SOLAR_PANELS');
            bind(app.WINDSlider, 'Value', app.Simulation.TunableVariables, 'WIND');
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINAL_MODEL0_1

            % Associate the Simulink Model
            app.Simulation = simulation('FINAL_MODEL');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end